classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Numberofteethanywherebetween12to300EditFieldLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        Gear1Label                      matlab.ui.control.Label
        Gear2Label                      matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2Label  matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2  matlab.ui.control.NumericEditField
        PinionGearDesignerLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDown_2Label         matlab.ui.control.Label
        MaterialDropDown_2              matlab.ui.control.DropDown
        ToothFormDropDownLabel          matlab.ui.control.Label
        ToothFormDropDown               matlab.ui.control.DropDown
        PowerRequiredEditFieldLabel     matlab.ui.control.Label
        PowerRequiredEditField          matlab.ui.control.EditField
        SelectDriverButtonGroup         matlab.ui.container.ButtonGroup
        Gear1Button                     matlab.ui.control.RadioButton
        Gear2Button                     matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox  matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function no_of_teeth1(app, event)
           app.EditField.Value = value1;
        end

        % Value changed function: 
        % Numberofteethanywherebetween12to300EditField_2
        function no_of_teeth2(app, event)
           app.EditField.Value = value2;
        end

        % Value changed function: ToothFormDropDown
        function Toothform(app, event)
           profile = app.ToothFormDropDown.Value;
        end

        % Button pushed function: CalculateButton
        function calculate(app, event)
            numofteeth1 = str2double(value1);
            y1 = LewisFactor(numofteeth1, profile);
            numofteeth2 = str2double(value2);
            y2 = LewisFactor(numofteeth2, profile);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Numberofteethanywherebetween12to300EditFieldLabel = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditFieldLabel.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [12 300];
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @no_of_teeth1, true);
            app.EditField.Value = 12;
            app.Gear1Label = uilabel(app.UIFigure);
            app.Gear1Label.Text = 'Gear 1';
            app.Gear2Label = uilabel(app.UIFigure);
            app.Gear2Label.Text = 'Gear 2';
            app.Numberofteethanywherebetween12to300EditField_2Label = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditField_2Label.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numberofteethanywherebetween12to300EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Numberofteethanywherebetween12to300EditField_2.Limits = [12 300];
            app.Numberofteethanywherebetween12to300EditField_2.RoundFractionalValues = 'on';
            app.Numberofteethanywherebetween12to300EditField_2.ValueChangedFcn = createCallbackFcn(app, @no_of_teeth2, true);
            app.Numberofteethanywherebetween12to300EditField_2.Value = 12;
            app.PinionGearDesignerLabel = uilabel(app.UIFigure);
            app.PinionGearDesignerLabel.Text = 'Pinion Gear Designer';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron', 'Phosphor Bronze', ''};
            app.MaterialDropDown.Value = 'Steel';
            app.MaterialDropDown_2Label = uilabel(app.UIFigure);
            app.MaterialDropDown_2Label.Text = 'Material';
            app.MaterialDropDown_2 = uidropdown(app.UIFigure);
            app.MaterialDropDown_2.Items = {'Steel', 'Cast Iron', 'Phosphor Bronze', ''};
            app.MaterialDropDown_2.Value = 'Steel';
            app.ToothFormDropDownLabel = uilabel(app.UIFigure);
            app.ToothFormDropDownLabel.Text = 'Tooth Form';
            app.ToothFormDropDown = uidropdown(app.UIFigure);
            app.ToothFormDropDown.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown.ValueChangedFcn = createCallbackFcn(app, @Toothform, true);
            app.ToothFormDropDown.Value = 'Involute 14.5';
            app.PowerRequiredEditFieldLabel = uilabel(app.UIFigure);
            app.PowerRequiredEditFieldLabel.Text = 'Power Required';
            app.PowerRequiredEditField = uieditfield(app.UIFigure, 'text');
            app.PowerRequiredEditField.Value = 'kW';
            app.SelectDriverButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectDriverButtonGroup.Title = 'Select Driver';
            app.Gear1Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Gear1Button.Text = 'Gear 1';
            app.Gear1Button.Value = true;
            app.Gear2Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Gear2Button.Text = 'Gear 2';
            app.IncludeDynamicAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.IncludeDynamicAnalysisCheckBox.Text = 'Include Dynamic Analysis';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end